classdef WormMarkerGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        SaveMidlinesButton_3  matlab.ui.control.Button
        UndoMidlineButton     matlab.ui.control.Button
        TogglecolorButton     matlab.ui.control.Button
        MarkMidlineButton     matlab.ui.control.Button
        SaveMaskButton        matlab.ui.control.Button
        LoadImageButton       matlab.ui.control.Button
        UIAxes                matlab.ui.control.UIAxes
    end


    properties (Access = private)
        im = []; % Description
        FluorescentIm = [];
        Mask = []; % Description
        climits = [];
        DiskSize = 2;
        Brights = 256;
        Exclusions = [];
        ExclusionTracker = [];
        Inclusions = [];
        InclusionTracker = [];
        File = [];
        Location = [];
        measures = {};
        BFcolor_map = 'parula';
        BFcolor_counter = 1;
        Seg_color_map = 'parula';
        Seg_color_counter = 1;
        mean_worm = [0.14 0.33 0.51 0.66 0.78 0.83 0.87 0.87 0.89 0.92 0.91 0.90 0.86 0.81 0.72 0.62 0.50 0.35 0.20 0.11];
    end

    methods (Access = private)


        function FL = FileListerTest(app,rootDirectory,fileDesciptor)
            FL.rootDirectory = rootDirectory;
            FL.fileDesciptor = fileDesciptor;
            FL.allFiles = listFiles(app,rootDirectory,fileDesciptor);
        end

        function [files] = listFiles(app, rootDirectory, filesDescriptor)
            % Local Files
            files = dir(fullfile(rootDirectory, filesDescriptor));
            for i=1:length(files)
                files(i).name = fullfile(rootDirectory,files(i).name);
            end
            % Local Dirs
            rootEntities = dir(rootDirectory);
            dirs = rootEntities([rootEntities.isdir]);
            dirs = dirs(3:end);

            % Iterating over local dirs
            for d=dirs'
                files = [files; listFiles(fullfile(rootDirectory, d.name), filesDescriptor)];
            end
        end

        function PlotMidlines(app)
            hold(app.UIAxes, 'on')

            for i = 1:size(app.measures,1)
                if ~isempty(app.measures{i,1})
                    x = app.measures{i,1}(:,1);
                    y = app.measures{i,1}(:,2);
                    plot(app.UIAxes,x,y, 'r', LineWidth= 2 )
                end
                if ~isempty(app.measures{i,1})
                    x = app.measures{i,2}(:,1);
                    y = app.measures{i,2}(:,2);
                    plot(app.UIAxes,x,y, 'b', LineWidth= 2 )
                end
            end
        end

        function [im,mask] = DrawSegmented(app)
            im = app.im;
            mask = app.Mask;
            inc = sum(app.InclusionTracker,3);
            exc = sum(app.ExclusionTracker,3);
            mask(find(inc)) = 1;
            mask(find(exc)) = 0;
            mask(im > app.Brights) = 0;
            mask = logical(mask);
            im(~mask) = 0;
            imagesc(app.UIAxes2, im)
            clim(app.UIAxes2, [app.climits(1), app.climits(2)])
        end

        function worm_mask = GetWormMask(app,xi,yi,width)
            [new_x, new_y] = InterpXY(app, xi, yi);

            mask_x = [];
            mask_y = [];
            % Assume you have the points defining the curve
            x = new_x;
            y = new_y;
            width_factor = 0.55;
            % Define the point at which you want the orthogonal vector
            for idx = 2:(length(new_x)-1)
                % Approximate the tangent vector at the specific point using finite difference
                dx = x(idx+1) - x(idx-1);
                dy = y(idx+1) - y(idx-1);
                % Find the orthogonal vector (in 2D, it's [-dy, dx])
                orthogonal_vector = [-dy, dx];
                % Normalize the orthogonal vector (optional, for a unit vector)
                orthogonal_vector = orthogonal_vector / norm(orthogonal_vector);
                % Define how far you want to extend the orthogonal line
                w = interp1(1:length(app.mean_worm), app.mean_worm, linspace(1, length(app.mean_worm), length(new_x)));
                w = width*w*width_factor;
                line_length = w(idx);  % Extend this distance in both directions

                % Calculate two endpoints for the orthogonal line
                p1 = [x(idx) + line_length * orthogonal_vector(1), y(idx) + line_length * orthogonal_vector(2)];
                p2 = [x(idx) - line_length * orthogonal_vector(1), y(idx) - line_length * orthogonal_vector(2)];

                % Use Bresenham's line algorithm to get the pixel indices of the orthogonal line
                x1 = round(p1(1)); y1 = round(p1(2));  % Start point
                x2 = round(p2(1)); y2 = round(p2(2));  % End point

                % MATLAB's built-in function to calculate Bresenham's line
                [orthogonal_line_x, orthogonal_line_y] = bresenham(app,x1, y1, x2, y2);
                mask_x = [mask_x; orthogonal_line_x];
                mask_y = [mask_y; orthogonal_line_y];
                % plot(orthogonal_line_x, orthogonal_line_y, 'r', 'LineWidth', 2);
                % % Plot the orthogonal line over the image

            end

            worm_mask = zeros(size(app.im));
            ind = sub2ind(size(worm_mask),mask_y, mask_x);
            worm_mask(ind) = 1;
            SE1 = strel("square",5);
            worm_mask= imclose(worm_mask, SE1);
            worm_mask = logical(worm_mask);

        end

        function [x y]= bresenham(app,x1,y1,x2,y2)
            %Matlab optmized version of Bresenham line algorithm. No loops.
            %Format:
            %               [x y]=bham(x1,y1,x2,y2)
            %
            %Input:
            %               (x1,y1): Start position
            %               (x2,y2): End position
            %
            %Output:
            %               x y: the line coordinates from (x1,y1) to (x2,y2)
            %
            %Usage example:
            %               [x y]=bham(1,1, 10,-5);
            %               plot(x,y,'or');
            x1=round(x1); x2=round(x2);
            y1=round(y1); y2=round(y2);
            dx=abs(x2-x1);
            dy=abs(y2-y1);
            steep=abs(dy)>abs(dx);
            if steep t=dx;dx=dy;dy=t; end
            %The main algorithm goes here.
            if dy==0
                q=zeros(dx+1,1);
            else
                q=[0;diff(mod([floor(dx/2):-dy:-dy*dx+floor(dx/2)]',dx))>=0];
            end
            %and ends here.
            if steep
                if y1<=y2 y=[y1:y2]'; else y=[y1:-1:y2]'; end
                if x1<=x2 x=x1+cumsum(q);else x=x1-cumsum(q); end
            else
                if x1<=x2 x=[x1:x2]'; else x=[x1:-1:x2]'; end
                if y1<=y2 y=y1+cumsum(q);else y=y1-cumsum(q); end
            end
        end

        function [interp_x, interp_y] = InterpXY(app, x, y)
            xq = min(x):0.1:max(x); % Query points

            x = x + rand(length(x),1)*0.1; % Make sure there are no identical values
            % Find increasing and decreasing segments
            increasing_mask = diff(x) > 0;
            decreasing_mask = diff(x) < 0;
         
            % Initialize interpolated y values
            interp_y = [];
            interp_x = [];
            i = 1;
            % Loop through and interpolate within each segment
            while i < (length(x)-1)
                if increasing_mask(i)
                    % Find next increase
                    segment_idx = find(decreasing_mask((i+1):end), 1, 'first') + i;
                    if isempty(segment_idx)
                        segment_idx = length(x);
                    end
                    x_segment = x(i:segment_idx);
                    y_segment = y(i:segment_idx);

                    % Interpolate for this segment
                    mask = (xq >= x_segment(1)) & (xq <= x_segment(end));
                    yq = interp1(x_segment, y_segment, xq(mask), 'linear');
                    interp_y = [interp_y , yq];
                    interp_x = [interp_x, xq(mask)];
                    i = segment_idx;
                elseif decreasing_mask(i)
                    % Find next decrease
                    segment_idx = find(increasing_mask((i+1):end), 1, 'first') + i;
                    if isempty(segment_idx)
                        segment_idx = length(x);
                    end
                    x_segment = x(i:segment_idx);
                    y_segment = y(i:segment_idx);

                    % Interpolate for this segment
                    mask = (xq >= x_segment(end)) & (xq <= x_segment(1));
                    yq = interp1(x_segment, y_segment, fliplr(xq(mask)), 'linear');
                    interp_y = [interp_y , yq];
                    interp_x = [interp_x, fliplr(xq(mask))];
                    i = segment_idx;
                end
            end

        end

        function ResetVariables(app)
            app.im = [];
            app.FluorescentIm = [];
            app.Mask = [];
            app.climits = [];
            app.DiskSize = 2;
            app.Brights = 256;
            app.Exclusions = [];
            app.ExclusionTracker = [];
            app.Inclusions = [];
            app.InclusionTracker = [];
            app.File = [];
            app.Location = [];
            app.measures = {};
            app.BFcolor_map = 'parula';
            app.BFcolor_counter = 1;
            app.Seg_color_map = 'parula';
            app.Seg_color_counter = 1;
            app.mean_worm = [0.14 0.33 0.51 0.66 0.78 0.83 0.87 0.87 0.89 0.92 0.91 0.90 0.86 0.81 0.72 0.62 0.50 0.35 0.20 0.11];
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            % randim = randi(1, 142)+2;
            % dirList = FileLister(Pdir,'*\.');
            % im = load([dirList.allFiles(randim,:).name, '\in.mat']);
            ResetVariables(app)
            [app.File,app.Location] = uigetfile('*');
            if strcmp(app.File((end-3):end), '.tif')
                im = imread([app.Location, app.File]);
                app.FluorescentIm = im;
            else
                im = load([app.Location, app.File]).in;
                app.FluorescentIm = im(:,:,1);
                im = im(:,:,2);
            end
            
            app.im = im;
            app.Mask = zeros(size(im,1), size(im,2));
            app.Exclusions = zeros(size(im,1), size(im,2));
            app.ExclusionTracker = [];
            imagesc(app.UIAxes, im)
            app.climits = [min(im,[],'all'), max(im,[],'all')];
            clim(app.UIAxes, app.climits)
            % app.OpenSlider.Enable
            % app.LinesSlider.Value = 5;
            % app.ThresholdSlider.Value = 0.3;
        end

        % Callback function
        function ThresholdThresholdSliderValueChanged(app, event)
            thresh = app.ThresholdSlider.Value(2);
            if thresh == 0
                app.Mask = ones(size(app.im,1), size(app.im,2));
            else

                Dsize = floor(26 - app.OpenSlider.Value(2));
                Lsize = app.LinesSlider.Value(2);

                img = app.im;
                edges = edge(img, 'Canny', thresh );
                SE1 = strel("line",Lsize,0);
                SE2 = strel("line",Lsize,90);
                SE3 = strel("line",Lsize,45);
                SE4 = strel("line",Lsize,135);
                SE5 = strel("disk",Dsize);
                closed_horz = imclose(edges, SE1);
                closed_vert = imclose(closed_horz, SE2);
                closed_diag1 = imclose(closed_vert, SE3);
                closed_diag2 = imclose(closed_diag1, SE4);
                filled_edges = imfill(closed_diag2, 'holes');
                croppedimg = img;
                croppedimg(~filled_edges) = 0;
                % figure;

                trimmed = imopen(croppedimg, SE5);
                croppedimg(trimmed == 0) = 0;
                b = imbinarize(croppedimg);
                r = regionprops('table',b,'Area','PixelIdxList');
                [~, bigblob] = max(r.Area);
                ind = r{bigblob, 'PixelIdxList'};
                if isempty(ind)
                    ind = {[]};
                end
                mask = zeros(size(croppedimg));
                mask(ind{1}) = 1;
                app.Mask(logical(mask)) = 1;
                app.Mask(~logical(mask)) = 0;

            end
            DrawSegmented(app);
            % croppedimg(~mask) = 0;
            % croppedimg(find(app.Exclusions)) = 0;
            % imagesc(app.UIAxes2, croppedimg)

            % clim(app.UIAxes2,[0, max(croppedimg(:))])
        end

        % Callback function
        function AddExclusion(app, event)

            % imagesc(app.UIAxes, app.im)
            im = app.im;
            wormROI = imfreehand(app.UIAxes2);
            m = wormROI.createMask;
            app.Exclusions(m) = 1;
            app.ExclusionTracker = cat(3, app.ExclusionTracker, m);
            DrawSegmented(app);
            % im(~app.Mask) = 0;
            % ex = sum(app.ExclusionTracker,3);
            % im(find(ex)) = 0;
            % imagesc(app.UIAxes2, im)
        end

        % Callback function
        function UndoExclusionButtonPushed(app, event)
            if size(app.ExclusionTracker,3) >= 1
                app.ExclusionTracker = app.ExclusionTracker(:,:,1:end-1);
                DrawSegmented(app);
            end
            % im = app.im;
            % im(~app.Mask) = 0;
            % ex = sum(app.ExclusionTracker,3);
            % im(find(ex)) = 0;
            % imagesc(app.UIAxes2, im)
        end

        % Callback function
        function ResetExclusionsButtonPushed(app, event)
            im = app.im;
            app.ExclusionTracker = [];
            app.Exclusions = zeros(size(im,1), size(im,2));
            im(~app.Mask) = 0;
            imagesc(app.UIAxes2, im);
        end

        % Callback function
        function MarkInclusionButtonPushed(app, event)
            im = app.im;
            wormROI = imfreehand(app.UIAxes2);
            m = wormROI.createMask;
            app.Inclusions(m) = 1;
            app.InclusionTracker = cat(3, app.InclusionTracker, m);
            DrawSegmented(app);
        end

        % Callback function
        function UndoInclusionButtonPushed(app, event)
            if size(app.InclusionTracker,3) >= 1
                app.InclusionTracker = app.InclusionTracker(:,:,1:end-1);
                DrawSegmented(app);
            end
            % im = app.im;
            % im(~app.Mask) = 0;
            % ex = sum(app.InclusionTracker,3);
            % im(find(ex)) = 1;
            % imagesc(app.UIAxes2, im)
        end

        % Button pushed function: SaveMaskButton
        function SaveMaskButtonPushed(app, event)
            % mask = app.Mask;
            % ex = sum(app.ExclusionTracker,3);
            % mask(find(ex)) = 0;
            % im = app.im;
            % im(~mask) = 0;
            [im,mask] = DrawSegmented(app);
            vals = app.FluorescentIm(im ~= 0);
            save([app.Location, strrep(app.File, '.mat',''), '_Pixels'], 'mask')
            save([app.Location, strrep(app.File, '.mat',''), '_Values'], 'vals')
        end

        % Button pushed function: MarkMidlineButton
        function MarkMidlineButtonPushed(app, event)
            hold(app.UIAxes, 'on')
            PlotMidlines(app)

            [xl,yl] = getpts(app.UIAxes);
            colormap(app.UIAxes, app.BFcolor_map)
            plot(app.UIAxes, xl, yl, 'r', LineWidth= 2 )
            measures{1} = [xl,yl];
            dist = 0;
            for p = 1:(length(xl) - 1)
                dist = dist + pdist([xl(p), yl(p);xl(p+1), yl(p+1)]);
            end
            [xw,yw] = getpts(app.UIAxes);
            plot(app.UIAxes, xw, yw, 'b', LineWidth= 2 )
            measures{2} = [xw,yw];
            width = pdist([xw(1), yw(1);xw(2), yw(2)]);
            measures{3} = dist;
            measures{4} = width;
            measures{5} = GetWormMask(app,xl,yl,width);
            app.measures = cat(1,app.measures,measures);


        end

        % Button pushed function: UndoMidlineButton
        function UndoMidlineButtonPushed(app, event)
            if size(app.measures,1) >= 1
                app.measures = app.measures(1:(end-1),:);
                imagesc(app.UIAxes, app.im)
                colormap(app.UIAxes, app.BFcolor_map)
                PlotMidlines(app)
            end

        end

        % Button pushed function: SaveMidlinesButton_3
        function SaveMidlinesButtonPushed(app, event)
            measures = app.measures;
            filename = strrep(app.File, '.mat','');
            filename = strrep(filename, '.tif','');
            save([app.Location, filename, '_measures'], 'measures')
        end

        % Callback function
        function KeepInclusionsOnlyButtonPushed(app, event)
            if size(app.InclusionTracker,3) >= 1
                app.Exclusions = [];
                app.ExclusionTracker = [];
                app.Mask = sum(app.InclusionTracker,3);
                DrawSegmented(app);
            end
        end

        % Callback function
        function rmBrightSliderValueChanged(app, event)
            app.Brights = 256;
            value = app.rmBrightSlider.Value(2);

            im = DrawSegmented(app);
            s = sort(im(im>0));
            app.Brights = s(floor(length(s)*value));
            DrawSegmented(app);
        end

        % Button pushed function: TogglecolorButton
        function TogglecolorButtonPushed(app, event)
            colors = {'parula', 'gray', 'summer','bone', 'copper','hsv','sky','abyss','jet', 'pink' };
            app.BFcolor_counter = app.BFcolor_counter + 1;

            app.BFcolor_map = colors{app.BFcolor_counter};
            if app.BFcolor_counter == length(colors)
                app.BFcolor_counter = 0;
            end
            imagesc(app.UIAxes, app.im)
            colormap(app.UIAxes, app.BFcolor_map)
            PlotMidlines(app)

        end

        % Callback function
        function TogglecolorButton_2Pushed(app, event)
            colors = {'parula', 'gray', 'summer','bone', 'copper','hsv','sky','abyss','jet', 'pink'};
            app.Seg_color_counter = app.Seg_color_counter + 1;

            app.Seg_color_map = colors{app.Seg_color_counter};
            if app.Seg_color_counter == length(colors)
                app.Seg_color_counter = 0;
            end

            DrawSegmented(app);
            colormap(app.UIAxes2, app.Seg_color_map)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.SaveMaskButton = uibutton(app.UIFigure, 'push');
            app.SaveMaskButton.ButtonPushedFcn = createCallbackFcn(app, @SaveMaskButtonPushed, true);
            app.SaveMaskButton.Text = 'Save Mask';
            app.MarkMidlineButton = uibutton(app.UIFigure, 'push');
            app.MarkMidlineButton.ButtonPushedFcn = createCallbackFcn(app, @MarkMidlineButtonPushed, true);
            app.MarkMidlineButton.Text = 'Mark Midline';
            app.TogglecolorButton = uibutton(app.UIFigure, 'push');
            app.TogglecolorButton.ButtonPushedFcn = createCallbackFcn(app, @TogglecolorButtonPushed, true);
            app.TogglecolorButton.Text = 'Toggle color';
            app.UndoMidlineButton = uibutton(app.UIFigure, 'push');
            app.UndoMidlineButton.ButtonPushedFcn = createCallbackFcn(app, @UndoMidlineButtonPushed, true);
            app.UndoMidlineButton.Text = 'Undo Midline';
            app.SaveMidlinesButton_3 = uibutton(app.UIFigure, 'push');
            app.SaveMidlinesButton_3.ButtonPushedFcn = createCallbackFcn(app, @SaveMidlinesButtonPushed, true);
            app.SaveMidlinesButton_3.Text = 'Save Midlines';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WormMarkerGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end